classdef app_carrito < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        InicioButton                    matlab.ui.control.Button
        VelocidadinicialmsEditField_2Label  matlab.ui.control.Label
        VelocidadinicialmsEditField_2   matlab.ui.control.NumericEditField
        resistenciadelaireEditFieldLabel  matlab.ui.control.Label
        resistenciadelaireEditField     matlab.ui.control.NumericEditField
        PesodelCarritokgEditFieldLabel  matlab.ui.control.Label
        PesodelCarritokgEditField       matlab.ui.control.NumericEditField
        coeficientedefriccionEditFieldLabel  matlab.ui.control.Label
        coeficientedefriccionEditField  matlab.ui.control.NumericEditField
        longituddelamontaaEditFieldLabel  matlab.ui.control.Label
        longituddelamontaaEditField     matlab.ui.control.NumericEditField
        posicioninicialEditFieldLabel   matlab.ui.control.Label
        posicioninicialEditField        matlab.ui.control.NumericEditField
        DtEditFieldLabel                matlab.ui.control.Label
        DtEditField                     matlab.ui.control.NumericEditField
        CenterPanel                     matlab.ui.container.Panel
        NormalCheckBox                  matlab.ui.control.CheckBox
        DragCheckBox                    matlab.ui.control.CheckBox
        FriccionCheckBox                matlab.ui.control.CheckBox
        GraficadelamontaaLabel          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        EnergiasLabel                   matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            v= app.VelocidadinicialmsEditField_2.Value;
            m= app.PesodelCarritokgEditField.Value;
            k= app.resistenciadelaireEditField.Value;
            mk = app.coeficientedefriccionEditField.Value;
            largo = app.longituddelamontaaEditField.Value;
            posinicial=app.posicioninicialEditField.Value;
            dt = app.DtEditField.Value;
            %% Grafica Montaña Rusa
            X = 0:.2:largo;
            Y = [];
            for x = 0:.2:largo
                paren = (pi*x)/25;
                y = 12*cos(paren) + 15;
                Y = [Y y];  
            end
            plot(app.UIAxes, X,Y,'r');
            title(app.UIAxes,"Montaña Rusa");
            ylabel(app.UIAxes,"Altura");
            xlabel(app.UIAxes,"Longitud");
            axis(app.UIAxes,[-10 largo+25 -2 50])
            %% Datos Iniciales para calcular posicion 
            Fun = @(x)(12*cos((pi*x)/25) + 15);
            FunD = @(x)((-12*pi*sin((pi*x)/25))/25);
            Fun2D = @(x)((-12*pi^2*cos((pi*x)/25))/625);
            g = 9.81;
            xprima = 0;
            xpos = posinicial;
            ypos = Fun(xpos);
            xgrafica = [];
            ygrafica = [];
            vcompro = [];
            em = [];
            ep = [];
            ec = [];
            dx = [];
            dy = [];
            Fnx = [];
            Fny = [];
            Frx= [];
            Fry=[];
            teta = atand(FunD(xpos));
            rc = ((1 + FunD(xpos)^2)^(3/2))/abs(Fun2D(xpos));
            energiapotencial = (m * g *ypos);
            energiacinetica = 1/2 * m * v^2;
            energiamecanica = (m * g *ypos) + (1/2 * m * v^2);
            fixed = energiamecanica + 10000;          
            
            for i = 1:500
                xprimaanterior = xprima;
                vanterior = v;
                xposanterior = xpos;
                yposanterior = ypos;
                rcanterior = rc;
                energiamecanicaA = energiamecanica;
                energiapotencialA = energiapotencial;
                energiacineticaA = energiacinetica;
                xgrafica = [xgrafica xposanterior];
                ygrafica = [ygrafica yposanterior];
                em = [em energiamecanicaA];
                ep = [ep energiapotencialA];
                ec = [ec energiacineticaA];
                xprima = xprimaanterior + vanterior*dt;
                teta = atan(FunD(xposanterior));
                dx = [dx sign(vanterior)*vanterior^2*k*-(cos(teta))];
                dy = [dy sign(vanterior)*vanterior^2*k*-(sin(teta))];
                Fnx = [Fnx -sin(teta)*(m*(g*cos(teta)+vanterior^2/rcanterior))];
                Fny = [Fny cos(teta)*(m*(g*cos(teta)+vanterior^2/rcanterior))];
                Frx= [Frx sign(vanterior)*-cos(teta)*(mk*m*abs(g*cos(teta)+vanterior^2/rcanterior))];
                Fry=[Fry sign(vanterior)*-sin(teta)*(mk*m*abs(g*cos(teta)+vanterior^2/rcanterior))];
                xpos = xposanterior + (xprima-xprimaanterior)*cos(teta);
                ypos = Fun(xpos);
                rc = ((1 + FunD(xpos)^2)^(3/2)/Fun2D(xpos));
                raizarriba = vanterior^2 * (1 - ((mk/rcanterior) + (k/m)) * abs(xprima-xprimaanterior)) - (2 * g * (ypos-yposanterior)) - (2 * mk * g * abs(xpos-xposanterior));
                raizabajo = (1 + ((mk/rc) + (k/m)) * abs(xprima - xprimaanterior));
                adentroraiz = raizarriba / raizabajo;
                
                if (adentroraiz > 0 && xpos < largo) && (adentroraiz > 0 && xpos > 0)
                   v = sign(vanterior) * (sqrt(adentroraiz));
                else
                    v = -1 * vanterior;
                    xprima = xprimaanterior;
                    xpos = xposanterior;
                    ypos = yposanterior;
                    rc = rcanterior;
                end
                energiapotencial = (m * g *ypos);
                energiacinetica = 1/2 * m * v^2;
                energiamecanica = (m * g *ypos) + (1/2 * m * v^2);
                
            end

            
            for i = 1:length(xgrafica)
                hold (app.UIAxes, "on")
                hold (app.UIAxes2, "on")
                plot(app.UIAxes,X,Y,'r');
                plot(app.UIAxes,xgrafica(i)-.45,ygrafica(i)+1,'b-s');
                plot(app.UIAxes,xgrafica(i),ygrafica(i)+1,'b-s');
                plot(app.UIAxes,xgrafica(i)+.45,ygrafica(i)+1,'b-s');
                plot(app.UIAxes,xgrafica(i)-.75,ygrafica(i)+.41,'k-o');
                plot(app.UIAxes,xgrafica(i)+.8,ygrafica(i)+.41,'k-o');
                c = categorical({'Mecanica','Potencial','Cinetica'});
                bar(app.UIAxes2, c(1),em(i));
                bar(app.UIAxes2, c(2),ep(i));
                bar(app.UIAxes2, c(3),ec(i));
                w=quiver(app.UIAxes, xgrafica(i),ygrafica(i),0,-m*g/50);
                w.Color ='m'
                w.LineWidth = 3
                if app.DragCheckBox.Value==1
                D=quiver(app.UIAxes, xgrafica(i),ygrafica(i)+.8,dx(i)/50,dy(i)/50); 
                D.Color ='k'
                D.LineWidth = 2
                end
                if app.NormalCheckBox.Value==1
                Fn=quiver(app.UIAxes, xgrafica(i),ygrafica(i),Fnx(i)/50,Fny(i)/50); 
                Fn.Color ='g'
                Fn.LineWidth = 3
                end
                if app.FriccionCheckBox.Value==1
                Fr=quiver(app.UIAxes, xgrafica(i),ygrafica(i),Frx(i)/50,Fry(i)/50);
                Fr.Color ='b'
                Fr.LineWidth = 2
                end
                pause(.0001);   
                cla(app.UIAxes);
                cla(app.UIAxes2)
                plot(app.UIAxes,X,Y,'r');
                ylabel(app.UIAxes,"Altura");
                xlabel(app.UIAxes,"Longitud");
                ylim(app.UIAxes2, [-2 fixed]);
                axis(app.UIAxes,[0 35 0 35]);
             end
            
                        
            hold (app.UIAxes, "off")
            hold (app.UIAxes2, "off")
            legend(app.UIAxes,"12cos((\pix)/25) + 15","Location","northeast");
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {596, 596, 596};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {596, 596};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {295, '1x', 456};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InicioButton = uibutton(app.LeftPanel, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.VelocidadinicialmsEditField_2Label = uilabel(app.LeftPanel);
            app.VelocidadinicialmsEditField_2Label.Text = 'Velocidad inicial m/s';
            app.VelocidadinicialmsEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.VelocidadinicialmsEditField_2.Value = -50;
            app.resistenciadelaireEditFieldLabel = uilabel(app.LeftPanel);
            app.resistenciadelaireEditFieldLabel.Text = 'resistencia del aire';
            app.resistenciadelaireEditField = uieditfield(app.LeftPanel, 'numeric');
            app.resistenciadelaireEditField.Value = 3;
            app.PesodelCarritokgEditFieldLabel = uilabel(app.LeftPanel);
            app.PesodelCarritokgEditFieldLabel.Text = 'Peso del Carrito kg';
            app.PesodelCarritokgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PesodelCarritokgEditField.Value = 100;
            app.coeficientedefriccionEditFieldLabel = uilabel(app.LeftPanel);
            app.coeficientedefriccionEditFieldLabel.Text = 'coeficiente de friccion';
            app.coeficientedefriccionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.coeficientedefriccionEditField.Value = 0.1;
            app.longituddelamontaaEditFieldLabel = uilabel(app.LeftPanel);
            app.longituddelamontaaEditFieldLabel.Text = 'longitud de la montaña';
            app.longituddelamontaaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.longituddelamontaaEditField.Value = 35;
            app.posicioninicialEditFieldLabel = uilabel(app.LeftPanel);
            app.posicioninicialEditFieldLabel.Text = 'posicion inicial';
            app.posicioninicialEditField = uieditfield(app.LeftPanel, 'numeric');
            app.posicioninicialEditField.Value = 30;
            app.DtEditFieldLabel = uilabel(app.LeftPanel);
            app.DtEditFieldLabel.Text = 'Dt';
            app.DtEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DtEditField.Value = 0.03;
            app.CenterPanel = uipanel(app.GridLayout);
            app.NormalCheckBox = uicheckbox(app.CenterPanel);
            app.NormalCheckBox.Text = 'Normal';
            app.NormalCheckBox.Value = true;
            app.DragCheckBox = uicheckbox(app.CenterPanel);
            app.DragCheckBox.Text = 'Drag';
            app.DragCheckBox.Value = true;
            app.FriccionCheckBox = uicheckbox(app.CenterPanel);
            app.FriccionCheckBox.Text = 'Friccion';
            app.FriccionCheckBox.Value = true;
            app.GraficadelamontaaLabel = uilabel(app.CenterPanel);
            app.GraficadelamontaaLabel.Text = 'Grafica de la montaña';
            app.Image = uiimage(app.CenterPanel);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'Fondo_montaña.jpeg';
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.EnergiasLabel = uilabel(app.RightPanel);
            app.EnergiasLabel.Text = 'Energias';
            app.UIAxes2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes2, 'Total')
            ylabel(app.UIAxes2, 'Joule')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_carrito

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end